classdef BehaviorBox_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        GraphPopOut_2                   matlab.ui.control.Button
        GraphPopOut                     matlab.ui.control.Button
        Folder                          matlab.ui.control.Button
        Git                             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        Level_EasyLvProb                matlab.ui.control.NumericEditField
        EasyLvProbLabel                 matlab.ui.control.Label
        Level_HardLvProb                matlab.ui.control.NumericEditField
        HighLvProbEditFieldLabel        matlab.ui.control.Label
        Level_HardLvList                matlab.ui.control.EditField
        Level_EasyLvList                matlab.ui.control.EditField
        InputControlPanel               matlab.ui.container.Panel
        ConfirmChoice                   matlab.ui.control.CheckBox
        Input_Delay_Respond             matlab.ui.control.NumericEditField
        Hold_Still_Thresh               matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        Input_Delay_Start               matlab.ui.control.NumericEditField
        DuringTrialEditFieldLabel       matlab.ui.control.Label
        IntertrialMalSec                matlab.ui.control.NumericEditField
        IntertrialMalCancel             matlab.ui.control.CheckBox
        Stimulus_FinishLine             matlab.ui.control.CheckBox
        RoundUpVal                      matlab.ui.control.NumericEditField
        RoundUpEditFieldLabel           matlab.ui.control.Label
        RoundUp                         matlab.ui.control.CheckBox
        TurnMag                         matlab.ui.control.NumericEditField
        TurnMagEditFieldLabel           matlab.ui.control.Label
        StimulusvariablesPanel          matlab.ui.container.Panel
        Stimulus_SegThick               matlab.ui.control.NumericEditField
        SegmentthicknessEditFieldLabel  matlab.ui.control.Label
        ReadyCue_Size                   matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        ReadyCue_Color                  matlab.ui.control.NumericEditField
        ReadyCuecolorLabel              matlab.ui.control.Label
        Stimulus_BackgroundColor        matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel        matlab.ui.control.Label
        Stimulus_DimColor               matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel_2      matlab.ui.control.Label
        Stimulus_FlashColor             matlab.ui.control.NumericEditField
        EditField_5Label_2              matlab.ui.control.Label
        Stimulus_LineColor              matlab.ui.control.NumericEditField
        LinebrightnessEditFieldLabel    matlab.ui.control.Label
        Stimulus_BetweenSpotlight       matlab.ui.control.NumericEditField
        BetweenSpotlight_textlabel      matlab.ui.control.Label
        Stimulus_SpotlightColor         matlab.ui.control.NumericEditField
        SpotlightbrightnessEditFieldLabel  matlab.ui.control.Label
        Stimulus_SegSpacing             matlab.ui.control.NumericEditField
        SegmentSpacingEditFieldLabel    matlab.ui.control.Label
        Stimulus_SegLength              matlab.ui.control.NumericEditField
        SegmentlengthEditFieldLabel     matlab.ui.control.Label
        BoxNum                          matlab.ui.control.NumericEditField
        BoxEditFieldLabel               matlab.ui.control.Label
        Arduino_Com                     matlab.ui.control.EditField
        text72                          matlab.ui.control.Label
        Stimulus_position_y             matlab.ui.control.EditField
        Stimulus_position_x             matlab.ui.control.EditField
        Stimulus_size_x                 matlab.ui.control.EditField
        Stimulus_size_y                 matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        Stimulus_CorrectAngleAdj        matlab.ui.control.NumericEditField
        CorrectionoffsetEditFieldLabel  matlab.ui.control.Label
        Stimulus_ContTol                matlab.ui.control.NumericEditField
        ContangletolEditFieldLabel      matlab.ui.control.Label
        Stimulus_RepFlashAfterW         matlab.ui.control.EditField
        Stimulus_RepFlashAfterC         matlab.ui.control.EditField
        Stimulus_FreqFlashAfter         matlab.ui.control.EditField
        Stimulus_RepFlashInitial        matlab.ui.control.EditField
        text122                         matlab.ui.control.Label
        Stimulus_FreqFlashInitial       matlab.ui.control.EditField
        text121                         matlab.ui.control.Label
        Stimulus_FlashStim              matlab.ui.control.CheckBox
        Stimulus_type                   matlab.ui.control.DropDown
        text65                          matlab.ui.control.Label
        SubjectPanel                    matlab.ui.container.Panel
        WeightgEditField                matlab.ui.control.EditField
        WeightgEditFieldLabel           matlab.ui.control.Label
        Strain                          matlab.ui.control.EditField
        text13                          matlab.ui.control.Label
        Inv                             matlab.ui.control.EditField
        Investigator                    matlab.ui.control.Label
        text86                          matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        Box_SecBwPulse                  matlab.ui.control.NumericEditField
        secbwPulsesEditFieldLabel       matlab.ui.control.Label
        Box_PulseMax                    matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Box_Pulse_Min                   matlab.ui.control.EditField
        Box_Variable_pulses             matlab.ui.control.CheckBox
        Box_Two_ports                   matlab.ui.control.CheckBox
        Box_RightPulse                  matlab.ui.control.NumericEditField
        Box_Rrewardtime                 matlab.ui.control.EditField
        RightValveButton                matlab.ui.control.Button
        Box_Juice                       matlab.ui.control.CheckBox
        Box_LeftPulse                   matlab.ui.control.NumericEditField
        Box_Lrewardtime                 matlab.ui.control.EditField
        LeftValveButton                 matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        Box_OCPulse                     matlab.ui.control.NumericEditField
        OnlyCorrect                     matlab.ui.control.CheckBox
        Data_Lbin                       matlab.ui.control.EditField
        Data_Sbin                       matlab.ui.control.EditField
        text35                          matlab.ui.control.Label
        Stimulus_PersistIncorrectInterv  matlab.ui.control.NumericEditField
        Stimulus_PersistIncorrect       matlab.ui.control.CheckBox
        Stimulus_PersistCorrectInterv   matlab.ui.control.NumericEditField
        Stimulus_PersistCorrect         matlab.ui.control.CheckBox
        Repeat_wrong                    matlab.ui.control.CheckBox
        SideBiasInterval                matlab.ui.control.NumericEditField
        SideBiassEditField_2Label       matlab.ui.control.Label
        Box_Timeout_after_time          matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        HoldStill                       matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        Intertrial_time                 matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        Penalty_time                    matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        Pokes_ignored_time              matlab.ui.control.EditField
        Input_ignored                   matlab.ui.control.CheckBox
        uipanel1                        matlab.ui.container.Panel
        RampMinLevel                    matlab.ui.control.NumericEditField
        RampMaxLevel                    matlab.ui.control.NumericEditField
        RampNum                         matlab.ui.control.NumericEditField
        Ramp                            matlab.ui.control.CheckBox
        prob_list                       matlab.ui.control.TextArea
        EasyTrials                      matlab.ui.control.CheckBox
        Starting_opacity                matlab.ui.control.Spinner
        DistractorsSpinnerLabel         matlab.ui.control.Label
        MaxRandAlt                      matlab.ui.control.EditField
        text119                         matlab.ui.control.Label
        MinRandAlt                      matlab.ui.control.EditField
        text117                         matlab.ui.control.Label
        Stimulus_side                   matlab.ui.control.DropDown
        text89                          matlab.ui.control.Label
        Box_Input_type                  matlab.ui.control.DropDown
        text66                          matlab.ui.control.Label
        TemporaryTab                    matlab.ui.container.Tab
        uipanel4_2                      matlab.ui.container.Panel
        Box_RightPulse_Temp             matlab.ui.control.NumericEditField
        Box_Rrewardtime_Temp            matlab.ui.control.EditField
        RightValveButton_Temp           matlab.ui.control.Button
        Box_LeftPulse_Temp              matlab.ui.control.NumericEditField
        Box_Lrewardtime_Temp            matlab.ui.control.EditField
        LeftValveButton_Temp            matlab.ui.control.Button
        ExpireAfterButtonGroup          matlab.ui.container.ButtonGroup
        TempOff_Temp                    matlab.ui.control.RadioButton
        TrialsRemainingLabel            matlab.ui.control.Label
        TrialCount_Temp                 matlab.ui.control.NumericEditField
        PerfThresh_Temp                 matlab.ui.control.NumericEditField
        TrialNumber_Temp                matlab.ui.control.RadioButton
        PerformanceThreshold_Temp       matlab.ui.control.RadioButton
        InputControlPanel_2             matlab.ui.container.Panel
        Hold_Still_Thresh_Temp          matlab.ui.control.NumericEditField
        EditField_6Label_2              matlab.ui.control.Label
        IntertrialMalSec_Temp           matlab.ui.control.NumericEditField
        IntertrialMalCancel_Temp        matlab.ui.control.CheckBox
        uipanel3_2                      matlab.ui.container.Panel
        Box_OCPulse_Temp                matlab.ui.control.NumericEditField
        OnlyCorrect_Temp                matlab.ui.control.CheckBox
        Repeat_wrong_Temp               matlab.ui.control.CheckBox
        HoldStill_Temp                  matlab.ui.control.NumericEditField
        EditField_5Label_3              matlab.ui.control.Label
        Intertrial_time_Temp            matlab.ui.control.EditField
        text10_2                        matlab.ui.control.Label
        Penalty_time_Temp               matlab.ui.control.EditField
        text8_2                         matlab.ui.control.Label
        Pokes_ignored_time_Temp         matlab.ui.control.EditField
        Input_ignored_Temp              matlab.ui.control.CheckBox
        uipanel1_2                      matlab.ui.container.Panel
        prob_list_Temp                  matlab.ui.control.TextArea
        EasyTrials_Temp                 matlab.ui.control.CheckBox
        Starting_opacity_Temp           matlab.ui.control.Spinner
        DistractorsSpinnerLabel_2       matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        NotesText                       matlab.ui.control.TextArea
        OutputTab                       matlab.ui.container.Tab
        MsgBox                          matlab.ui.control.TextArea
        PerformanceTab                  matlab.ui.container.Tab
        TimersTab                       matlab.ui.container.Tab
        AllTimeTab                      matlab.ui.container.Tab
        WeightWaterTab                  matlab.ui.container.Tab
        OtherUnusedTab                  matlab.ui.container.Tab
        ArduinosDropDown                matlab.ui.control.DropDown
        ArduinosDropDownLabel           matlab.ui.control.Label
        EquipmentPanel                  matlab.ui.container.Panel
        BaseButton                      matlab.ui.control.Button
        VideoButton                     matlab.ui.control.Button
        Curtain                         matlab.ui.control.Button
        SaveCompButton                  matlab.ui.control.Button
        LoadCompButton                  matlab.ui.control.Button
        ResetAll                        matlab.ui.control.StateButton
        OpenBoth                        matlab.ui.control.StateButton
        OpenR                           matlab.ui.control.StateButton
        OpenL                           matlab.ui.control.StateButton
        Ext_trigger                     matlab.ui.control.CheckBox
        uipanel8                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Train                           matlab.ui.control.Button
        Stimulus_TrainingFlashifWrong   matlab.ui.control.CheckBox
        TrainingChoices                 matlab.ui.control.DropDown
        Show_two_bars                   matlab.ui.control.CheckBox
        uipanel12                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        stdthreshold                    matlab.ui.control.EditField
        text124                         matlab.ui.control.Label
        sub_bin_size                    matlab.ui.control.EditField
        text123                         matlab.ui.control.Label
        RandomMin                       matlab.ui.control.EditField
        text100                         matlab.ui.control.Label
        RandomStep                      matlab.ui.control.EditField
        text99                          matlab.ui.control.Label
        RandomMax                       matlab.ui.control.EditField
        text98                          matlab.ui.control.Label
        StepUpAfter                     matlab.ui.control.EditField
        text97                          matlab.ui.control.Label
        text96                          matlab.ui.control.Label
        LowerThres                      matlab.ui.control.EditField
        text95                          matlab.ui.control.Label
        RaiseThres                      matlab.ui.control.EditField
        text94                          matlab.ui.control.Label
        LowerDiffAfterBins              matlab.ui.control.EditField
        Lower_bg_with_perf              matlab.ui.control.CheckBox
        RaiseDiffAfterBins              matlab.ui.control.EditField
        Raise_bg_with_perf              matlab.ui.control.CheckBox
        DiffAdjustMethod                matlab.ui.control.DropDown
        uipanel11                       matlab.ui.container.Panel
        Stimulus_Orient                 matlab.ui.control.EditField
        Ori_Frequency                   matlab.ui.control.EditField
        Ori_Bar_Size                    matlab.ui.control.EditField
        Ori_Random                      matlab.ui.control.CheckBox
        Ori_Interval                    matlab.ui.control.EditField
        Ori_Orientations                matlab.ui.control.DropDown
        Ori_Duration                    matlab.ui.control.EditField
        Ori_Repeats                     matlab.ui.control.EditField
        Orientation                     matlab.ui.control.Button
        text85                          matlab.ui.control.Label
        text84                          matlab.ui.control.Label
        text83                          matlab.ui.control.Label
        text82                          matlab.ui.control.Label
        text81                          matlab.ui.control.Label
        text79                          matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        text17                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        Subject                         matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        ShowStim                        matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Stop                            matlab.ui.control.StateButton
        Skip                            matlab.ui.control.StateButton
        Pause                           matlab.ui.control.StateButton
        FastForward                     matlab.ui.control.StateButton
        Start                           matlab.ui.control.Button
        Water                           matlab.ui.control.Button
        PrintSetup                      matlab.ui.control.Button
        text15                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        text30                          matlab.ui.control.Label
        text25                          matlab.ui.control.Label
        text29                          matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text26                          matlab.ui.control.Label
        text28                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        text1                           matlab.ui.control.Label
    end

    properties (Access = public)
        %Set = struct()
        %a % Arduino
        %BB % The BehaviorBox* Class, Nose or Wheel
    end
    methods (Access = private)
        function PreviewStimulus(app, hObject, eventdata, handles)
            app.text1.Text = 'Previewing stimulus...';
            BB = evalin('base','BB');
            try
                BB.getGUI();
                BB.TestStimulus();
            catch err
                BB.unwrapError(err)
                BB.cleanUP;
            end
        end

        function FindArduino(app, handles)
            arguments
                app
                handles
            end
            if ~isempty(app.Arduino_Com.Value)
                return
            end
            sl = serialportlist("Available");
            if isempty(sl)
                sl = serialportlist("All");
            end
            sl(contains(sl, 'Bluetooth', IgnoreCase=true)) = [];
            if isunix
                sl = sl(contains(sl, '/dev/ttyACM', IgnoreCase=true));
            elseif ispc
                sl(contains(sl, {'COM1', 'COM3'}, IgnoreCase=true)) = [];
            end
            if isempty(sl)
                return
            end
            app.ArduinosDropDown.Items = sl;
            COMSlist = sl;
            if ~isempty(app.Arduino_Com.Value)
                %Leave it
            elseif ~isempty(COMSlist)
                if ispc
                    COMSnum = COMSlist{1}(regexp(COMSlist{1}, '\d'));
                elseif ismac
                    whichPort = contains(sl, 'cu.usbmodem');
                    if sum(whichPort) == 1
                        COMSnum = sl(whichPort);
                    end
                elseif isunix
                    whichPort = contains(sl, '/dev/tty');
                    if sum(whichPort) == 1
                        wc = split(sl(whichPort), '/dev/tty');
                        COMSnum = wc(2);
                    else
                        wsl = sl(whichPort);
                        wc = split(wsl(1), '/dev/tty');
                        COMSnum = wc(2);
                    end
                end
                app.Arduino_Com.Value = COMSnum; drawnow;
            else
                app.text1.Text = 'No available COMs found, check USB connections or use Keyboard Input.' ;
            end
        end

        function changeGUIvalue(app, field_handle, change)
            Old_Entry=str2double(get(field_handle,'String'));
            New_Entry=Old_Entry+change;
            if New_Entry<0
                New_Entry=0;
            end
            set(field_handle,'String',num2str(New_Entry));
        end

        function loadGuiInputAsStruct(app, handles, ~)
            try
                BB = evalin("base", "BB"); 
            catch
                app.BehaviorBox_OpeningFcn
                BB = evalin("base", "BB");
            end
            %Read settings from interface:
            Sub = {app.Subject.Value};
            Invest = app.Inv.Value;
            Inp = app.Box_Input_type.Value;
            Str = app.Strain.Value;
            BBData = BehaviorBoxData("Inv", Invest, "Inp", Inp, "Sub",Sub, find=1);
            if isempty(handles.Strain.String) || all(handles.Strain.String ~= string(BBData.Str))
                handles.Strain.String = BBData.Str; drawnow;
            end
            try
                if isempty(BBData.fds) || numel(BBData.fds.Files) == 0
                    disp('No saved settings found for current subject, using current values...');
                    return
                else
                    filename = BBData.fds.Files{end};
                    tree = split(filename, filesep);
                end
                Set = BBData.loadedData{end,3}.Settings;
                Set = Set(1);
                handles.Strain.String = Set.Strain;
                BBData.Str = Set.Strain;
            catch
                msg = 'Problem loading, check files... using current values';
                disp(msg);
                app.text1.Text = msg;
            end
            try
                skipnames = {'Strain', 'Investigator', 'Weight','Subject','Arduino_Com','prob_list'};
                names = fieldnames(Set);
                namesREF = fieldnames(Set);
                names(contains(names, 'Box')) = [];
                names(contains(names, 'Temp')) = [];
                names(contains(names, 'Arduino')) = [];
                names(matches(names, skipnames)) = [];
                % Add back in all the temporary settings
                names = [names ; namesREF(contains(namesREF, 'Temp'))];
                for s = names'
                    try
                        if app.(s{:}).Type == "uieditfield"
                            if class(Set.(s{:})) == "double"
                                app.(s{:}).Value = num2str(Set.(s{:}));
                            else
                                app.(s{:}).Value = Set.(s{:});
                            end
                        elseif app.(s{:}).Type == "uidropdown"
                            app.(s{:}).Value = app.(s{:}).Items(Set.(s{:}));                            
                        elseif app.(s{:}).Type == "uicheckbox"
                            app.(s{:}).Value = Set.(s{:});                            
                        elseif app.(s{:}).Type == "uinumericeditfield"
                            app.(s{:}).Value = Set.(s{:});
                        elseif app.(s{:}).Type == "uispinner"
                            app.(s{:}).Value = Set.(s{:});
                        elseif app.(s{:}).Type == "uiradiobutton"
                            app.(s{:}).Value = Set.(s{:});
                        elseif app.(s{:}).Type == "uitextarea"
                            app.(s{:}).Value = Set.(s{:});
                        end
                    catch err
                        try
                            if class(Set.(s{:})) ~= "char"
                                handles.(s{:}).String = Set.(s{:});
                            end
                        catch
                            sprintf("Problem: "+s{:})
                        end
                    end
                end
            catch err
                disp(err.message)
            end
            pause(0.01) % Update GUI
            try
                BBData.AnalyzeAllData();
                Ax = BBData.plotLvByDayOneAxis(LevDay=0, Sc=1, Training=1);
                Ax.PickableParts = "none";
                clo(app.AllTimeTab)
                copyobj(Ax, app.AllTimeTab)
                close(Ax.Parent.Parent)
            catch
            end
            pause(0.01) % Update GUI
            try
                t = BBData.PlotWeightAndWater();
                clo(app.WeightWaterTab)
                copyobj(t, app.WeightWaterTab)
                close(t.Parent)
            catch
            end
            app.WeightgEditField.Value = '';
            pause(0.01) % Update GUI
            BB.getGUI();
            BB.Data_Object = BBData;
            assignin("base", "BB", BB)
            assignin("base", "BBData", BBData)
            text = "Settings synced.";
            fprintf('%s\n',text)
            app.text1.Text = text;
            pause(0.01) % Update GUI
        end

        function LoadComputerSpecifics(app)
            % if ispc
            %     loadDir = fullfile(getenv('USERPROFILE'), 'Desktop', 'BehaviorBox');
            % elseif ismac
            %     loadDir = '/Users/willsnyder/Dropbox (Dropbox @RU)/Dropbox (Dropbox @RU)/Gilbert Lab/BehaviorBoxData/Data/';
            % elseif isunix
            %     loadDir = fullfile(getenv('HOME'), 'Desktop', 'BehaviorBox');
            % end
            loadDir = GetFilePath("Computer");
            saveFiles = dir(loadDir);
            COM = app.Arduino_Com.Value;
            if isempty(COM)
                COM = "NoArd";
            end 
            wF = saveFiles(contains({saveFiles.name}, ['ComputerSettings' COM]));
            if isempty(wF) || numel(wF) > 1
                disp("Please check the Arduino Port and try again.")
                return
            end
            load(fullfile(wF.folder, wF.name), 'SavedComputerSpecifics')
            for f = fieldnames(SavedComputerSpecifics)'
                try
                    app.(f{:}).Value = SavedComputerSpecifics.(f{:});
                catch err % Fails for the Position property
                    1;
                end
            end
            app.figure1.Position = SavedComputerSpecifics.Position;
        end

        function SaveComputerSpecifics(app)
            %Settings will be specific to the COM number
            props = properties(app);
            names = cellfun(@(x)app.(x).Tag, properties(app), 'UniformOutput', false);
            boxstuff = props(contains(names, 'box_','IgnoreCase',true));
            stimstuff = props(contains(names, 'stimulus_','IgnoreCase',true));
            allstuff = [boxstuff' stimstuff'];
            SavedComputerSpecifics = struct();
            for i = allstuff
                SavedComputerSpecifics.(i{:}) = app.(i{:}).Value;
            end
            SavedComputerSpecifics.edit22 = app.Arduino_Com.Value;
            SavedComputerSpecifics.Position = app.figure1.Position;
            COM = app.Arduino_Com.Value;
            if isempty(COM)
                COM = "NoArd";
            end
            saveasname = fullfile(GetFilePath("Computer"), "ComputerSettings"+COM+".mat");
            save(saveasname, 'SavedComputerSpecifics')
            app.text1.Text = 'Computer settings saved.';
            fprintf('Computer settings saved.\n')
        end

        function Out = GetGui(app, handles)
            % Migrate this fcn from BBSuper
            Settings = struct();
            Out = struct();

            % this.stop_handle = this.app.Stop; %Buttons
            % this.Skip = this.app.Skip;
            % this.FF = this.app.FastForward;
            % this.Pause = this.app.Pause;
            % this.message_handle = this.app.text1;
            % this.GUI_numbers.trial_no = 0; %GUI Numbers and their handles on the figure
            % this.GUI_numbers.choices = 0;
            % this.GUI_numbers.difficulty = 0;
            % this.GUI_numbers.time = 0;
            % this.GUI_numbers.handle.trial_no = this.app.text16; %Every input must be a string, not a number
            % this.GUI_numbers.handle.choices = this.app.text19;
            % this.GUI_numbers.handle.difficulty = this.app.text17;
            % this.GUI_numbers.handle.time = this.app.text3;
            props = properties(app); %Get all names
            props(props == "MsgBox") = [];
            props(props == "TextArea2") = [];
            props(props == "Set") = [];
            props(props == "a") = [];
            skiptypes = {'buttongroup', 'figure', 'label', 'panel', 'annotationpane', 'axes', 'tab', 'uigridlayout'};
            types = GetType(app, props); %cellfun(@(x) app.(x).Type, props, 'UniformOutput', false); %Get their types
            props = props(~contains(types, skiptypes, "IgnoreCase",true));
            types = GetType(app, props);
            %Make Button structure
            buttons = props(contains(types, {'button'}) & ~contains(types, {'radiobutton'}));
            bTags = GetTag(app, buttons);
            Buttons = cell2struct(cellfun(@(x)(app.(x)), buttons, 'UniformOutput',false), bTags);
            props = props(~contains(types, {'button'}) | contains(types, {'radiobutton'})); types = GetType(app, props);
            %Make Dropdown structure
            appProps = props;
            appPropsTypes = types;
            Dropdowns = props(contains(types, {'dropdown'}));
            dTags = GetTag(app, Dropdowns);
            DropVals = cellfun(@(x)find(matches(app.(x).Items, app.(x).Value)), Dropdowns, 'UniformOutput',false);
            dropdowns = cell2struct(DropVals, dTags);
            props = props(~contains(types, {'dropdown'})); types = GetType(app, props);
            %Make Checkbox structure
            tempVal = cell(size(props));
            CIdx = contains(types, {'check'});
            Checkboxes = props(CIdx);
            cVals = cellfun(@(x)logical(app.(x).Value), Checkboxes, 'UniformOutput',false);
            tempVal(CIdx) = cVals;
            pTags = GetTag(app, props);
            tempVal(~CIdx) = cellfun(@(x)str2double(app.(x).Value), props(~CIdx), 'UniformOutput',false);
            ReDo = cellfun(@isnan, tempVal, 'UniformOutput',true);
            tempVal(ReDo) = cellfun(@(x)(app.(x).Value), props(ReDo), 'UniformOutput',false);
            tempSetting_Struct = cell2struct(tempVal, pTags);
            tempSetting_Struct = appendStruct(tempSetting_Struct, dropdowns);
            Out = app.makeTrialStructures(tempSetting_Struct);
            Settings = tempSetting_Struct;
            %Get the settings label from the setting structure, to label the data.
            SetIdx = 1;
            Out.LevelStruct = ManyLevels(app, Out.LevelStruct);
            Out.Setting_Struct = Settings;
            Out.Buttons = Buttons;

            %[SetStr, Include] = structureSettings(Setting_Struct);
            function Types = GetType(App, Props)
                Types = cellfun(@(x) App.(x).Type, Props, 'UniformOutput', false, 'ErrorHandler', @errorFuncNaN);
            end
            function Types = GetTag(App, Props)
                Types = cellfun(@(x) App.(x).Tag, Props, 'UniformOutput', false);
            end
        end

        function Out = makeTrialStructures(app, Settings)
            Out = struct();
            Out.StimulusStruct = struct();
            Out.Box = struct();
            Out.ReadyCueStruct = struct();
            Out.LevelStruct = struct();
            Out.Temp_Settings = struct();
            Out.StimulusStruct = appendStruct(Out.StimulusStruct, PullOut(Settings, 'Stimulus_'));
            Out.Box = appendStruct(Out.Box, PullOut(Settings, 'Box_'));
            Out.ReadyCueStruct = appendStruct(Out.ReadyCueStruct, PullOut(Settings, 'ReadyCue_'));
            Out.LevelStruct = appendStruct(Out.LevelStruct, PullOut(Settings, 'Level_'));
            Out.Temp_Settings = appendStruct(Out.Temp_Settings, PullOut(Settings, '_Temp'));
            function OUT = PullOut(IN, chr)
                OUT = struct();
                names = fieldnames(IN);
                inter = names(contains(names, chr));
                vals = cellfun(@(x)IN.(x), inter, "UniformOutput",false);
                CIDX = contains(inter, "color", IgnoreCase=true);
                vals(CIDX) = cellfun(@(x)repmat(x,1,3), vals(CIDX), "UniformOutput",false);
                OUT = cell2struct(vals, erase(inter, chr));
            end
        end

        function OUT = ManyLevels(app, In)
            OUT = In;
            if ~isnumeric(In.HardLvList)
                HardLevs = str2num(In.HardLvList);
            else
                HardLevs = In.HardLvList;
            end
            if ~isnumeric(In.EasyLvList)
                EasyLevs = str2double(In.EasyLvList);
            else
                EasyLevs = In.EasyLvList;
            end
            LEVELS = {EasyLevs HardLevs; In.EasyLvProb In.HardLvProb};
            PossibleLevels = [];
            for L = LEVELS
                levs = L{1};
                p = ceil((L{2}*100)/numel(levs));
                for l = levs
                    PossibleLevels = [PossibleLevels repmat(l, 1, p)];
                end
            end
            OUT.PossibleLevels = PossibleLevels;
            OUT.ChooseLevel = @(x)OUT.PossibleLevels(randperm(numel(PossibleLevels), 1));
        end

        function printHardwareconnections(~, handles)
            current_selection_stimulus=get(handles.Box_Input_type,'Value');
            Two_ports=get(handles.Box_Two_ports,'Value');
            Two_puffs = 0;
            Air_Puff_Penalty = 1;
            fprintf('------SET UP-------\n')
            switch current_selection_stimulus
                case 1
                    fprintf('Contour crude stimulus\n')
                case 2
                    fprintf('Contour fine stimulus\n')
                case 3
                    fprintf('Square Vs Open Box crude stimulus\n')
                case 4
                    fprintf('Square Vs Open Box fine stimulus\n')
                case 5
                    fprintf('Match Target stimulus\n')
                case 11
                    fprintf('Contour Density: Identify the dashed-line contour\n')
            end
            current_selection_input=get(handles.Box_Input_type,'Value');
            switch current_selection_input
                case 1
                    fprintf('One Lever Input\n')
                    fprintf('- connect One Lever Input to Arduino pin analog 2\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 2
                    fprintf('Two Lever Input\n')
                    fprintf('- connect Left Lever Input to Arduino pin D2\n')
                    fprintf('- connect Right Lever Input to Arduino pin D7\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 3
                    fprintf('Three Pokes Input\n')
                    fprintf('- connect Center Poke Input to Arduino pin D3\n')
                    fprintf('- connect Left Poke Input to Arduino pin D2\n')
                    fprintf('- connect Right Poke Input to Arduino pin D7\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    if Air_Puff_Penalty == 1
                        if Two_puffs == 0
                            fprintf('- connect puff valve output to Arduino pin D11\n')
                        else
                            fprintf('- connect left puff valve output to Arduino pin D11\n')
                            fprintf('- connect right puff valve output to Arduino pin D12\n')
                        end
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 4
                    fprintf('Rotating Ball Input\n')
                    fprintf('- connect Left Turn Input to Arduino pin D2\n')
                    fprintf('- connect Right Turn Input to Arduino pin D7\n')
                    if ~Two_ports
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- connect reset output to Arduino pin D4\n')
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 5
                    fprintf('Lick Sensor Input\n')
                    fprintf('- connect Center Lick Sensor Input to Arduino pin D3\n')
                    fprintf('- connect Left Lick Sensor Input to Arduino pin D2\n')
                    fprintf('- connect Right Lick Sensor Input to Arduino pin D7\n')
                    fprintf('- connect Left water reward valve to Arduino pin D8\n')
                    fprintf('- connect Right water reward valve to Arduino pin D6\n')
                    fprintf('- connect AirPuff penalty valve to Arduino pin D11\n')
                case 6
                    fprintf('Wheel Input: Accu-Coder Rotary Encoder \n')
                    fprintf('- connect Encoder Power (white wire) to Arduino +5V \n')
                    fprintf('- connect Encoder Ground (black wire) to Arduino GND \n')
                    fprintf('- connect A (brown wire) to Arduino pin D2 \n')
                    fprintf('- connect B (red wire) to Arduino pin D3 \n')
                    fprintf('- connect Water reward valve to Arduino pin D6\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
            end
            fprintf('------------------\n');
        end
        function printWelcomeMsg(~)
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
            fprintf("888888b.            888                        d8b                  888888b.                     ");
            fprintf('\n');
            fprintf('888  "88b           888                        Y8P                  888  "88b                    ');
            fprintf('\n');
            fprintf("888  .88P           888                                             888  .88P                    ");
            fprintf('\n');
            fprintf("8888888K.   .d88b.  88888b.   8888b.  888  888 888  .d88b.  888d888 8888888K.   .d88b.  888  888 ");
            fprintf('\n');
            fprintf('888  "Y88b d8P  Y8b 888 "88b     "88b 888  888 888 d88""88b 888P"   888  "Y88b d88""88b `Y8bd8P');
            fprintf('\n');
            fprintf('888    888 88888888 888  888 .d888888 Y88  88P 888 888  888 888     888    888 888  888   X88K   ');
            fprintf('\n');
            fprintf('888   d88P Y8b.     888  888 888  888  Y8bd8P  888 Y88..88P 888     888   d88P Y88..88P .d8""8b. ');
            fprintf('\n');
            fprintf('8888888P"   "Y8888  888  888 "Y888888   Y88P   888  "Y88P"  888     8888888P"   "Y88P"  888  888 ');
            fprintf('\n');
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
        end

        function results = LvProbChange(~, In)
            results = 1-In;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BehaviorBox_OpeningFcn(app)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            %OPENING FUNCTION
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to BehaviorBox (see VARARGIN)
            handles.output = hObject;
            % **************** ADD THIS SECTION ******************
            %from: https://www.mathworks.com/matlabcentral/answers/482510-annotationpane-handle-appearing-in-guide-guis-with-panel-axes-in-r2019b
            % Check if scribeOverlay is a field and that it contains an annotation pane
            if isfield(handles,'scribeOverlay') && isa(handles.scribeOverlay(1),'matlab.graphics.shape.internal.AnnotationPane')
                delete(handles.scribeOverlay);
                handles = rmfield(handles, 'scribeOverlay');
            end
            % **********************  END ************************
            % Update handles structure
            guidata(hObject, handles);
            printWelcomeMsg(app)
            cd(GetFilePath("Computer")) %Change to app's folder on Desktop
            addpath("fcns/")
            % Make Diary to log stdout:
            diary off
            app.MsgBox.Value = ''; drawnow
            try
                delete('BBAppoutput.txt');
            end
            diary('BBAppoutput.txt');
            sl = serialportlist("Available");
            if ~isempty(app.Arduino_Com.Value) && ~contains(app.Arduino_Com.Value, 'ACM', IgnoreCase=true) | ~isnumeric(app.Arduino_Com.Value)
                app.Arduino_Com.Value = '';
            end
            try
                app.FindArduino(handles);
                LoadComputerSpecifics(app)
            end
            printHardwareconnections(app, handles) %Print out welcome message and some instructions
            app.text1.Text = 'Setting up...';
            fprintf('Setting up...\n');
            app.MsgBox.Value = fileread('BBAppoutput.txt'); drawnow;
            if app.Box_Input_type.Value == "Wheel"
                BB = BehaviorBoxWheel(handles, app);
                if app.ResetAll.Value
                    BB.ConfigureArduino("Rebuild",false);
                else
                    BB.ConfigureArduino();
                end
            else
                BB = BehaviorBoxNose(handles, app);
                if app.ResetAll.Value
                    BB.ConfigureArduino("Rebuild",false);
                else
                    BB.ConfigureArduino();
                end
            end
            try
                assignin("base", "BB", BB)
            end
            if app.Subject.Value ~= "w" %Look up subject if one is filled in
                loadGuiInputAsStruct(app, handles, 0)
            end
            % Clean Up:
            app.text1.Text = 'Ready';
            fprintf('Ready\n');
            app.MsgBox.Value = fileread('BBAppoutput.txt'); drawnow;
            diary off
            BB.toggleButtonsOnOff(BB.Buttons, 1)
        end

        % Button pushed function: Start
        function Start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %run regular trials
            %START BUTTON
            try
                evalin('base','clc')
                if app.Box_Input_type.Value == "NosePoke"
                    trialObject = evalin('base','BB');
                elseif app.Box_Input_type.Value == "Wheel"
                    trialObject = evalin('base','BB');
                else
                    trialObject = evalin('base','BB');
                end
                trialObject.RunTrials();
                SaveComputerSpecifics(app);
            catch err
                disp(err.message);
                trialObject.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: Train
        function Train_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %TRAINING BUTTON
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %run training
            try
                %Constructor functions, SetUp arduino and variables
                ThisTrial = BehaviorBoxSuper(handles, app);
                %Start the training
                ThisTrial.RunTraining();
            catch err
                disp(err.message);
                ThisTrial.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: PrintSetup
        function PrintSetup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %PRINT CONNECTION AND PINS NEEDED
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            printHardwareconnections(app, handles)
        end

        % Button pushed function: ShowStim
        function ShowStim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % show PREVIEW STIMULUS BUTTON
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PreviewStimulus(app, hObject, eventdata, handles);
        end

        % Button pushed function: LoadButton
        function LoadButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % LOAD SUBJECT SETTINGS
            % hObject    handle to pushbutton18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadGuiInputAsStruct(app, handles, 0);
        end

        % Button pushed function: Orientation
        function Orientation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % ORIENTATION TUNING ONLY
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                %run orientationTuning
                ThisTrial = BehaviorBoxSub2(handles);
                ThisTrial.RunOrientationTuning();
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: Water
        function WaterPushed(app, event)
            %To test water output:
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            % TEST BOX
            try
                if ismember('BB', evalin('base','who'))
                    BB = evalin('base','BB');
                    BB.TextBox();
                else
                    BehaviorBox_OpeningFcn(app)
                    BB.TextBox();
                end
                SaveComputerSpecifics(app)
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: LoadCompButton
        function LoadCompButtonPushed(app, event)
            % LOAD COMPUTER SETTINGS
            %To load the computer specific settings (such as window size
            %and position and water drop sizes)
            LoadComputerSpecifics(app);
        end

        % Button pushed function: SaveCompButton
        function SaveCompButtonPushed(app, event)
            % SAVE COMPUTER SETTINGS
            %To save the computer specific settings (such as window size
            %and position and water drop sizes) to a folder on the
            %desktop
            SaveComputerSpecifics(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            evalin('base', 'clear a BB BBSuper');
            delete(findobj("Type", "figure", "Name", "Stimulus"))
            %delete(findobj("Type", "figure", "Name", "Graphs"))
        end

        % Button pushed function: LeftValveButton
        function LeftValveButtonPushed(app, event)
            % L TEST WATER BUTTON
            a = evalin('base', 'BB.a');
            Valve = 'D8'; %Left
            Time = str2double(app.Box_Lrewardtime.Value);
            Rep = app.Box_LeftPulse.Value;
            % command to pulse valve
            for i = 1:Rep
                a.writeDigitalPin(Valve,1)
                pause(Time);
                a.writeDigitalPin(Valve,0)
                if i<Rep
                    pause(app.Box_SecBwPulse.Value)
                end
            end
        end

        % Button pushed function: RightValveButton
        function RightValveButtonPushed(app, event)
            % R TEST WATER BUTTON
            a = evalin('base', 'BB.a');
            Valve = 'D6'; %Right
            Time = str2double(app.Box_Rrewardtime.Value);
            % command to pulse valve
            for i = 1:app.Box_RightPulse.Value
                a.writeDigitalPin(Valve,1)
                pause(Time);
                a.writeDigitalPin(Valve,0)
                if i<app.Box_RightPulse.Value
                    pause(app.Box_SecBwPulse.Value)
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %RESET BUTTON
            evalin('base', 'clear hidden; clc')
            evalin('base', 'clear a BB');  drawnow;
            BehaviorBox_OpeningFcn(app)
        end

        % Value changed function: OpenL
        function OpenLValueChanged(app, event)
            value = app.OpenL.Value;
            a = evalin('base', 'BB.a');
            Valve = 'D8'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
        end

        % Value changed function: OpenR
        function OpenRValueChanged(app, event)
            value = app.OpenR.Value;
            a = evalin('base', 'BB.a');
            Valve = 'D6'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
        end

        % Value changed function: OpenBoth
        function OpenBothValueChanged(app, event)
            value = app.OpenBoth.Value;
            a = evalin('base', 'BB.a');
            Valve = 'D8'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
            Valve = 'D6'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
        end

        % Value changed function: ResetAll
        function RESETALLButtonPushed(app, event)
            app.ResetAll.Text = 'Resetting...';
            evalin('base', 'clear all hidden classes; clc'); drawnow;
            try
                BehaviorBox_OpeningFcn(app)
            end
            app.ResetAll.Value = 0;
            app.ResetAll.Text = 'Reset ALL!'; drawnow;
        end

        % Value changed function: Subject
        function SubjectValueChanged(app, event)
            value = app.Subject.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            loadGuiInputAsStruct(app, handles, 0);
        end

        % Button pushed function: Git
        function GitButtonPushed(app, event)
            evalin("base", "!git stash && git pull")
        end

        % Button pushed function: Folder
        function FolderButtonPushed(app, event)
            try
                BB = evalin("base", "BB");
                if ispc
                    winopen(BB.Data_Object.filedir{:})
                end
            end
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            try
                viewDualCameras();
            end
        end

        % Button pushed function: Curtain
        function CurtainButtonPushed(app, event)
            try
                Stim = BehaviorBoxVisualStimulus();
                Stim.CurtainOn();
            end
        end

        % Value changed function: Level_EasyLvProb
        function Level_EasyLvProbValueChanged(app, event)
            app.Level_HardLvProb.Value = 1-app.Level_EasyLvProb.Value;
            app.GetGui();
        end

        % Value changed function: Level_HardLvProb
        function Level_HardLvProbValueChanged(app, event)
            app.Level_EasyLvProb.Value = 1-app.Level_HardLvProb.Value;
            app.GetGui();
        end

        % Callback function: Box_LeftPulse, Box_Lrewardtime,
        % Box_RightPulse, 
        % ...and 72 other components
        function GUIValueChange(app, event)
            % Any time a value is changed this function will re-produce the Setting
            % Structures
            app.GetGui();
        end

        % Button pushed function: GraphPopOut
        function GraphPopOutButtonPushed(app, event)
            try
                oldFig = app.AllTimeTab.Children;
                if isempty(oldFig)
                    return
                end
                newFig = MakeAxis();
                H = copyobj(oldFig,newFig.Parent);
                assignin("base", "H", H)
            end
        end

        % Button pushed function: GraphPopOut_2
        function GraphPopOut_2ButtonPushed(app, event)
            try
                oldFig = app.PerformanceTab.Children;
                if isempty(oldFig)
                    return
                end
                newFig = MakeAxis();
                H = copyobj(oldFig,newFig.Parent);
                assignin("base", "H", H)
            end
        end

        % Button pushed function: BaseButton
        function BaseButtonPushed(app, event)
            %assignin("base", "BB", app.BB)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'colorcube');
            app.figure1.Name = 'BehaviorBox';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'idle';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Left';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Right ';
            app.text28 = uilabel(app.figure1);
            app.text28.Tag = 'text28';
            app.text28.Text = '-';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = '-';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Rewards';
            app.text29 = uilabel(app.figure1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'total correct:';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = '0';
            app.text30 = uilabel(app.figure1);
            app.text30.Tag = 'text30';
            app.text30.Text = '--';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Time(min):';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = '-';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'choices';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'difficulty';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = '0';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Trial Number';
            app.PrintSetup = uibutton(app.figure1, 'push');
            app.PrintSetup.ButtonPushedFcn = createCallbackFcn(app, @PrintSetup_Callback, true);
            app.PrintSetup.Tag = 'PrintSetup';
            app.PrintSetup.Tooltip = 'show all connections required in command window';
            app.PrintSetup.Text = 'Setup';
            app.Water = uibutton(app.figure1, 'push');
            app.Water.ButtonPushedFcn = createCallbackFcn(app, @WaterPushed, true);
            app.Water.Tag = 'Water';
            app.Water.Text = 'Test Box';
            app.Start = uibutton(app.figure1, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @Start_Callback, true);
            app.Start.Tag = 'Start';
            app.Start.Tooltip = 'Start the complete training';
            app.Start.Text = 'Start';
            app.FastForward = uibutton(app.figure1, 'state');
            app.FastForward.Tag = 'FastForward';
            app.FastForward.Tooltip = {'Click this button to fast-forward through the current pause interval'};
            app.FastForward.Icon = fullfile(pathToMLAPP, 'imgs', 'Forward_font_awesome.svg.png');
            app.FastForward.Text = '';
            app.Pause = uibutton(app.figure1, 'state');
            app.Pause.Tag = 'Pause';
            app.Pause.Icon = fullfile(pathToMLAPP, 'imgs', 'pause.png');
            app.Pause.Text = '';
            app.Skip = uibutton(app.figure1, 'state');
            app.Skip.Tag = 'Skip';
            app.Skip.Icon = fullfile(pathToMLAPP, 'imgs', 'skip.png');
            app.Skip.Text = '';
            app.Stop = uibutton(app.figure1, 'state');
            app.Stop.Tag = 'Stop';
            app.Stop.Tooltip = 'Stop all';
            app.Stop.Text = 'Stop';
            app.ResetButton = uibutton(app.figure1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tag = 'ResetButton';
            app.ResetButton.Tooltip = {'Click to clear memory and reconnect Arduino.'};
            app.ResetButton.Text = 'Reset';
            app.ShowStim = uibutton(app.figure1, 'push');
            app.ShowStim.ButtonPushedFcn = createCallbackFcn(app, @ShowStim_Callback, true);
            app.ShowStim.Tag = 'ShowStim';
            app.ShowStim.Tooltip = {'Press to generate an example of the current level. May crash if the flash routine hasn''t finished.'};
            app.ShowStim.Text = 'Preview';
            app.LoadButton = uibutton(app.figure1, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_Callback, true);
            app.LoadButton.Tag = 'Subject';
            app.LoadButton.Tooltip = {'Open mouse''s folder'};
            app.LoadButton.Text = 'Subject:';
            app.Subject = uieditfield(app.figure1, 'text');
            app.Subject.ValueChangedFcn = createCallbackFcn(app, @SubjectValueChanged, true);
            app.Subject.Tag = 'Subject';
            app.Subject.Tooltip = 'Enter animal number here';
            app.Subject.Value = 'w';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = '0';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = '0';
            app.TabGroup = uitabgroup(app.figure1);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.uipanel1 = uipanel(app.SettingsTab);
            app.uipanel1.Title = 'Level & Side';
            app.uipanel1.Tag = 'uipanel1';
            app.text66 = uilabel(app.uipanel1);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Input:';
            app.Box_Input_type = uidropdown(app.uipanel1);
            app.Box_Input_type.Items = {'One Lever', 'Two Levers', 'NosePoke', 'Rotating Ball', 'Lick Sensor', 'Wheel', 'Lick Go/No-Go', 'Keyboard'};
            app.Box_Input_type.Tag = 'Box_Input_type';
            app.Box_Input_type.Tooltip = 'choose input';
            app.Box_Input_type.Value = 'NosePoke';
            app.text89 = uilabel(app.uipanel1);
            app.text89.Tag = 'text89';
            app.text89.Text = 'Side:';
            app.Stimulus_side = uidropdown(app.uipanel1);
            app.Stimulus_side.Items = {'Random', 'Left only', 'Right only', 'Alternate repeat', 'Alternate random', 'Side-Bias Correction', 'Pseudo-Random', 'Keyboard'};
            app.Stimulus_side.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_side.Tag = 'Stimulus_side';
            app.Stimulus_side.Tooltip = 'choose stimulus';
            app.Stimulus_side.Value = 'Random';
            app.text117 = uilabel(app.uipanel1);
            app.text117.Tag = 'text117';
            app.text117.Text = 'Alt. Random:';
            app.MinRandAlt = uieditfield(app.uipanel1, 'text');
            app.MinRandAlt.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.MinRandAlt.Tag = 'MinRandAlt';
            app.MinRandAlt.Tooltip = 'What the starting opacity is in complete training';
            app.MinRandAlt.Value = '2';
            app.text119 = uilabel(app.uipanel1);
            app.text119.Tag = 'text119';
            app.text119.Text = '<';
            app.MaxRandAlt = uieditfield(app.uipanel1, 'text');
            app.MaxRandAlt.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.MaxRandAlt.Tag = 'MaxRandAlt';
            app.MaxRandAlt.Tooltip = 'What the starting opacity is in complete training';
            app.MaxRandAlt.Value = '4';
            app.DistractorsSpinnerLabel = uilabel(app.uipanel1);
            app.DistractorsSpinnerLabel.Text = 'Level';
            app.Starting_opacity = uispinner(app.uipanel1);
            app.Starting_opacity.Limits = [1 20];
            app.Starting_opacity.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Starting_opacity.Tag = 'Starting_opacity';
            app.Starting_opacity.Value = 1;
            app.EasyTrials = uicheckbox(app.uipanel1);
            app.EasyTrials.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.EasyTrials.Tag = 'EasyTrials';
            app.EasyTrials.Tooltip = 'Defines if easier trials should be shown or not';
            app.EasyTrials.Text = 'Easy %';
            app.EasyTrials.Value = true;
            app.prob_list = uitextarea(app.uipanel1);
            app.prob_list.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.prob_list.Tag = 'prob_list';
            app.Ramp = uicheckbox(app.uipanel1);
            app.Ramp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Ramp.Tag = 'Ramp';
            app.Ramp.Text = 'Ramp';
            app.RampNum = uieditfield(app.uipanel1, 'numeric');
            app.RampNum.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.RampNum.Tag = 'RampNum';
            app.RampNum.Tooltip = {'How many trials at each Level in the Ramp?'};
            app.RampMaxLevel = uieditfield(app.uipanel1, 'numeric');
            app.RampMaxLevel.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.RampMaxLevel.Tag = 'RampMaxLevel';
            app.RampMaxLevel.Tooltip = {'Maximum Level'};
            app.RampMinLevel = uieditfield(app.uipanel1, 'numeric');
            app.RampMinLevel.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.RampMinLevel.Tag = 'RampMinLevel';
            app.RampMinLevel.Tooltip = {'Minimum Level'};
            app.uipanel3 = uipanel(app.SettingsTab);
            app.uipanel3.Title = 'Trial Intervals';
            app.uipanel3.Tag = 'uipanel3';
            app.Input_ignored = uicheckbox(app.uipanel3);
            app.Input_ignored.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Input_ignored.Tag = 'Input_ignored';
            app.Input_ignored.Text = 'Input ignored';
            app.Pokes_ignored_time = uieditfield(app.uipanel3, 'text');
            app.Pokes_ignored_time.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Pokes_ignored_time.Tag = 'Pokes_ignored_time';
            app.Pokes_ignored_time.Tooltip = 'ignore all input for how many seconds after trial begin';
            app.Pokes_ignored_time.Value = '2';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Penalty(s)';
            app.Penalty_time = uieditfield(app.uipanel3, 'text');
            app.Penalty_time.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Penalty_time.Tag = 'Penalty_time';
            app.Penalty_time.Tooltip = 'penalty delay in seconds';
            app.Penalty_time.Value = '1';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Intertrial (s)';
            app.Intertrial_time = uieditfield(app.uipanel3, 'text');
            app.Intertrial_time.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Intertrial_time.Tag = 'Intertrial_time';
            app.Intertrial_time.Tooltip = 'Interval between each trial in seconds';
            app.Intertrial_time.Value = '1';
            app.EditField_5Label = uilabel(app.uipanel3);
            app.EditField_5Label.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.EditField_5Label.Text = 'Hold still (s):';
            app.HoldStill = uieditfield(app.uipanel3, 'numeric');
            app.HoldStill.Limits = [0 10];
            app.HoldStill.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.HoldStill.Tag = 'HoldStill';
            app.HoldStill.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Timeout (s)';
            app.Box_Timeout_after_time = uieditfield(app.uipanel3, 'text');
            app.Box_Timeout_after_time.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Box_Timeout_after_time.Tag = 'Box_Timeout_after_time';
            app.Box_Timeout_after_time.Tooltip = 'trial times out and next one begins after how many seconds';
            app.Box_Timeout_after_time.Value = '0';
            app.SideBiassEditField_2Label = uilabel(app.uipanel3);
            app.SideBiassEditField_2Label.Text = 'SideBias (s):';
            app.SideBiasInterval = uieditfield(app.uipanel3, 'numeric');
            app.SideBiasInterval.Limits = [0.05 0.5];
            app.SideBiasInterval.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.SideBiasInterval.Tag = 'SideBiasInterval';
            app.SideBiasInterval.Value = 0.25;
            app.Repeat_wrong = uicheckbox(app.uipanel3);
            app.Repeat_wrong.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Repeat_wrong.Tag = 'Repeat_wrong';
            app.Repeat_wrong.Tooltip = 'repeat the same side if last one was wrong';
            app.Repeat_wrong.Text = 'repeat wrong';
            app.Stimulus_PersistCorrect = uicheckbox(app.uipanel3);
            app.Stimulus_PersistCorrect.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_PersistCorrect.Tag = 'Stimulus_PersistCorrect';
            app.Stimulus_PersistCorrect.Text = 'Persist correct?';
            app.Stimulus_PersistCorrect.Value = true;
            app.Stimulus_PersistCorrectInterv = uieditfield(app.uipanel3, 'numeric');
            app.Stimulus_PersistCorrectInterv.Limits = [0 60];
            app.Stimulus_PersistCorrectInterv.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_PersistCorrectInterv.Tag = 'Stimulus_PersistCorrectInterv';
            app.Stimulus_PersistCorrectInterv.Value = 1;
            app.Stimulus_PersistIncorrect = uicheckbox(app.uipanel3);
            app.Stimulus_PersistIncorrect.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_PersistIncorrect.Tag = 'Stimulus_PersistIncorrect';
            app.Stimulus_PersistIncorrect.Text = 'Persist incorrect?';
            app.Stimulus_PersistIncorrectInterv = uieditfield(app.uipanel3, 'numeric');
            app.Stimulus_PersistIncorrectInterv.Limits = [0 60];
            app.Stimulus_PersistIncorrectInterv.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_PersistIncorrectInterv.Tag = 'Stimulus_PersistIncorrectInterv';
            app.Stimulus_PersistIncorrectInterv.Value = 1;
            app.text35 = uilabel(app.uipanel3);
            app.text35.Tag = 'text35';
            app.text35.Text = 'binsize:';
            app.Data_Sbin = uieditfield(app.uipanel3, 'text');
            app.Data_Sbin.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Data_Sbin.Tag = 'Data_Sbin';
            app.Data_Sbin.Tooltip = {'How many trials in a small bin'};
            app.Data_Sbin.Value = '10';
            app.Data_Lbin = uieditfield(app.uipanel3, 'text');
            app.Data_Lbin.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Data_Lbin.Tag = 'Data_Lbin';
            app.Data_Lbin.Tooltip = {'How many small bins in a full bin'};
            app.Data_Lbin.Value = '2';
            app.OnlyCorrect = uicheckbox(app.uipanel3);
            app.OnlyCorrect.Tag = 'OnlyCorrect';
            app.OnlyCorrect.Text = 'Only Correct';
            app.Box_OCPulse = uieditfield(app.uipanel3, 'numeric');
            app.Box_OCPulse.Limits = [0 10];
            app.Box_OCPulse.Tag = 'Box_OCPulse';
            app.Box_OCPulse.Tooltip = {'How many drops to give if using the Only Correct setting, where the mouse is able to answer correctly after a wrong choice.'};
            app.Box_OCPulse.Value = 1;
            app.uipanel4 = uipanel(app.SettingsTab);
            app.uipanel4.Title = 'Reward / Penalty';
            app.uipanel4.Tag = 'uipanel4';
            app.LeftValveButton = uibutton(app.uipanel4, 'push');
            app.LeftValveButton.ButtonPushedFcn = createCallbackFcn(app, @LeftValveButtonPushed, true);
            app.LeftValveButton.Tag = 'LeftValveButton';
            app.LeftValveButton.Tooltip = {'Press to test Left water reward size'};
            app.LeftValveButton.Text = 'L';
            app.Box_Lrewardtime = uieditfield(app.uipanel4, 'text');
            app.Box_Lrewardtime.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Box_Lrewardtime.Tag = 'Box_Lrewardtime';
            app.Box_Lrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Box_Lrewardtime.Value = '0.04';
            app.Box_LeftPulse = uieditfield(app.uipanel4, 'numeric');
            app.Box_LeftPulse.Limits = [1 10];
            app.Box_LeftPulse.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Box_LeftPulse.Tag = 'Box_LeftPulse';
            app.Box_LeftPulse.Tooltip = {'How many pulses from the left water valve'};
            app.Box_LeftPulse.Value = 1;
            app.Box_Juice = uicheckbox(app.uipanel4);
            app.Box_Juice.Tag = 'Box_Juice';
            app.Box_Juice.Tooltip = {'Check this box if the mice are getting fruit juice instead of water.'};
            app.Box_Juice.Text = 'Juice?';
            app.RightValveButton = uibutton(app.uipanel4, 'push');
            app.RightValveButton.ButtonPushedFcn = createCallbackFcn(app, @RightValveButtonPushed, true);
            app.RightValveButton.Tag = 'RightValveButton';
            app.RightValveButton.Tooltip = {'Press to test Right water reward size'};
            app.RightValveButton.Text = 'R';
            app.Box_Rrewardtime = uieditfield(app.uipanel4, 'text');
            app.Box_Rrewardtime.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Box_Rrewardtime.Tag = 'Box_Rrewardtime';
            app.Box_Rrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Box_Rrewardtime.Value = '0.04';
            app.Box_RightPulse = uieditfield(app.uipanel4, 'numeric');
            app.Box_RightPulse.Limits = [1 10];
            app.Box_RightPulse.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Box_RightPulse.Tag = 'Box_RightPulse';
            app.Box_RightPulse.Tooltip = {'How many pulses from the rightwater valve'};
            app.Box_RightPulse.Value = 1;
            app.Box_Two_ports = uicheckbox(app.uipanel4);
            app.Box_Two_ports.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Box_Two_ports.Tag = 'Box_Two_ports';
            app.Box_Two_ports.Tooltip = 'use pulses instead of single reward';
            app.Box_Two_ports.Text = '2 valves';
            app.Box_Two_ports.Value = true;
            app.Box_Variable_pulses = uicheckbox(app.uipanel4);
            app.Box_Variable_pulses.Tag = 'Box_Variable_pulses';
            app.Box_Variable_pulses.Tooltip = {'If selected a random # of pulses between the bounds will be given rather than the same fixed amount each time.'};
            app.Box_Variable_pulses.Text = 'Variable?';
            app.Box_Pulse_Min = uieditfield(app.uipanel4, 'text');
            app.Box_Pulse_Min.Tag = 'Box_Pulse_Min';
            app.Box_Pulse_Min.Tooltip = {'Starting number of pulses'};
            app.Box_Pulse_Min.Value = '1';
            app.Label = uilabel(app.uipanel4);
            app.Label.Text = '<';
            app.Box_PulseMax = uieditfield(app.uipanel4, 'text');
            app.Box_PulseMax.Tag = 'Box_PulseMax';
            app.Box_PulseMax.Tooltip = {'Maximum number of pulses'};
            app.Box_PulseMax.Value = '1';
            app.secbwPulsesEditFieldLabel = uilabel(app.uipanel4);
            app.secbwPulsesEditFieldLabel.Text = 'sec b/w Pulses';
            app.Box_SecBwPulse = uieditfield(app.uipanel4, 'numeric');
            app.Box_SecBwPulse.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Box_SecBwPulse.Tag = 'Box_SecBwPulse';
            app.Box_SecBwPulse.Value = 0.2;
            app.text86 = uilabel(app.SettingsTab);
            app.text86.Tag = 'text86';
            app.text86.Text = 'WBS Feb. 2024';
            app.SubjectPanel = uipanel(app.SettingsTab);
            app.SubjectPanel.Title = 'Subject';
            app.Investigator = uilabel(app.SubjectPanel);
            app.Investigator.Tag = 'text128';
            app.Investigator.Text = 'Investigator';
            app.Inv = uieditfield(app.SubjectPanel, 'text');
            app.Inv.Tag = 'Investigator';
            app.Inv.Tooltip = 'Name of person conducting the training';
            app.Inv.Value = 'Will';
            app.text13 = uilabel(app.SubjectPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Strain';
            app.Strain = uieditfield(app.SubjectPanel, 'text');
            app.Strain.Tag = 'Strain';
            app.Strain.Tooltip = 'Enter animal strain e.g. Shank3, 16b';
            app.Strain.Value = 'w';
            app.WeightgEditFieldLabel = uilabel(app.SubjectPanel);
            app.WeightgEditFieldLabel.Text = 'Weight';
            app.WeightgEditField = uieditfield(app.SubjectPanel, 'text');
            app.WeightgEditField.Tag = 'Weight';
            app.WeightgEditField.Tooltip = {'Enter the animal''s weight in grams'};
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = 'Panel';
            app.text65 = uilabel(app.Panel_2);
            app.text65.Tag = 'text65';
            app.text65.Text = 'Stimulus:';
            app.Stimulus_type = uidropdown(app.Panel_2);
            app.Stimulus_type.Items = {'Contour crude', 'Contour fine ', 'Square Vs O crude', 'Square Vs O fine', 'unknown', 'XvsO', 'Two Task Contour', 'Cued Images', 'Distract Contour', 'Grating', 'Contour Density', 'BehaviorBox Practice'};
            app.Stimulus_type.Tag = 'Stimulus_type';
            app.Stimulus_type.Tooltip = 'choose stimulus';
            app.Stimulus_type.Value = 'Contour Density';
            app.Stimulus_FlashStim = uicheckbox(app.Panel_2);
            app.Stimulus_FlashStim.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_FlashStim.Tag = 'Stimulus_FlashStim';
            app.Stimulus_FlashStim.Text = 'flash';
            app.Stimulus_FlashStim.Value = true;
            app.text121 = uilabel(app.Panel_2);
            app.text121.Tag = 'text121';
            app.text121.Text = 'freq';
            app.Stimulus_FreqFlashInitial = uieditfield(app.Panel_2, 'text');
            app.Stimulus_FreqFlashInitial.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_FreqFlashInitial.Tag = 'Stimulus_FreqFlashInitial';
            app.Stimulus_FreqFlashInitial.Tooltip = 'Frequency of flashing stimulus';
            app.Stimulus_FreqFlashInitial.Value = '1';
            app.text122 = uilabel(app.Panel_2);
            app.text122.Tag = 'text122';
            app.text122.Text = 'reps';
            app.Stimulus_RepFlashInitial = uieditfield(app.Panel_2, 'text');
            app.Stimulus_RepFlashInitial.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_RepFlashInitial.Tag = 'Stimulus_RepFlashInitial';
            app.Stimulus_RepFlashInitial.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.Stimulus_RepFlashInitial.Value = '0';
            app.Stimulus_FreqFlashAfter = uieditfield(app.Panel_2, 'text');
            app.Stimulus_FreqFlashAfter.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_FreqFlashAfter.Tag = 'Stimulus_FreqFlashAfter';
            app.Stimulus_FreqFlashAfter.Tooltip = 'Frequency of flashing stimulus';
            app.Stimulus_FreqFlashAfter.Value = '1';
            app.Stimulus_RepFlashAfterC = uieditfield(app.Panel_2, 'text');
            app.Stimulus_RepFlashAfterC.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_RepFlashAfterC.Tag = 'Stimulus_RepFlashAfterC';
            app.Stimulus_RepFlashAfterC.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.Stimulus_RepFlashAfterC.Value = '0';
            app.Stimulus_RepFlashAfterW = uieditfield(app.Panel_2, 'text');
            app.Stimulus_RepFlashAfterW.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_RepFlashAfterW.Tag = 'Stimulus_RepFlashAfterW';
            app.Stimulus_RepFlashAfterW.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.Stimulus_RepFlashAfterW.Value = '0';
            app.ContangletolEditFieldLabel = uilabel(app.Panel_2);
            app.ContangletolEditFieldLabel.Text = 'Cont. angle tol.';
            app.Stimulus_ContTol = uieditfield(app.Panel_2, 'numeric');
            app.Stimulus_ContTol.Tag = 'Stimulus_ContTol';
            app.Stimulus_ContTol.Tooltip = {'Stimuli are checked for accidental continuation and any angles below this threshold are adjusted'};
            app.Stimulus_ContTol.Value = 10;
            app.CorrectionoffsetEditFieldLabel = uilabel(app.Panel_2);
            app.CorrectionoffsetEditFieldLabel.Text = 'Correction offset';
            app.Stimulus_CorrectAngleAdj = uieditfield(app.Panel_2, 'numeric');
            app.Stimulus_CorrectAngleAdj.Tag = 'Stimulus_CorrectAngleAdj';
            app.Stimulus_CorrectAngleAdj.Tooltip = {'When correcting the randomly oriented segments, lines are offset by a random angle between this and the tolerance.'};
            app.Stimulus_CorrectAngleAdj.Value = 45;
            app.StimulusvariablesPanel = uipanel(app.SettingsTab);
            app.StimulusvariablesPanel.Title = 'Stimulus variables';
            app.Stimulus_size_y = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_size_y.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_size_y.Tag = 'Stimulus_size_y';
            app.Stimulus_size_y.Tooltip = {'Height in pixels'};
            app.Stimulus_size_y.Value = '3';
            app.Stimulus_size_x = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_size_x.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_size_x.Tag = 'Stimulus_size_x';
            app.Stimulus_size_x.Tooltip = {'Width in pixels'};
            app.Stimulus_size_x.Value = '10';
            app.Stimulus_position_x = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_position_x.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_position_x.Tag = 'Stimulus_position_x';
            app.Stimulus_position_x.Tooltip = {'X position in pixels'};
            app.Stimulus_position_x.Value = '1';
            app.Stimulus_position_y = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_position_y.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_position_y.Tag = 'Stimulus_position_y';
            app.Stimulus_position_y.Tooltip = {'Y position in pixels'};
            app.Stimulus_position_y.Value = '1';
            app.text72 = uilabel(app.StimulusvariablesPanel);
            app.text72.Tag = 'text72';
            app.text72.Text = 'com:';
            app.Arduino_Com = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Arduino_Com.Tag = 'Arduino_Com';
            app.Arduino_Com.Tooltip = 'com port to use for arduino';
            app.BoxEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.BoxEditFieldLabel.Text = 'Box #:';
            app.BoxNum = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.BoxNum.Tag = 'BoxNum';
            app.BoxNum.Tooltip = {'Put the Box/Monitor number in this spot. Each mouse should always use the same box.'};
            app.SegmentlengthEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentlengthEditFieldLabel.Text = 'Segment length';
            app.Stimulus_SegLength = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SegLength.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_SegLength.Tag = 'Stimulus_SegLength';
            app.Stimulus_SegLength.Value = 13;
            app.SegmentSpacingEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentSpacingEditFieldLabel.Text = 'Segment Spacing';
            app.Stimulus_SegSpacing = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SegSpacing.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_SegSpacing.Tag = 'Stimulus_SegSpacing';
            app.Stimulus_SegSpacing.Value = 13;
            app.SpotlightbrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SpotlightbrightnessEditFieldLabel.Text = 'Spotlight brightness';
            app.Stimulus_SpotlightColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SpotlightColor.Limits = [0 1];
            app.Stimulus_SpotlightColor.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_SpotlightColor.Tag = 'Stimulus_SpotlightColor';
            app.Stimulus_SpotlightColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_SpotlightColor.Value = 0.2;
            app.BetweenSpotlight_textlabel = uilabel(app.StimulusvariablesPanel);
            app.BetweenSpotlight_textlabel.Tooltip = {'Percentage of the screen space that is left blank between each spotlight'};
            app.BetweenSpotlight_textlabel.Text = 'Between spotlight (%)';
            app.Stimulus_BetweenSpotlight = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_BetweenSpotlight.Limits = [0 99];
            app.Stimulus_BetweenSpotlight.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_BetweenSpotlight.Tag = 'Stimulus_BetweenSpotlight';
            app.Stimulus_BetweenSpotlight.Tooltip = {'Percentage of the screen space that is left blank between each spotlight. 33% is the default for the nose poke, use a smaller value for the wheel.'};
            app.LinebrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.LinebrightnessEditFieldLabel.Text = 'Line brightness';
            app.Stimulus_LineColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_LineColor.Limits = [0 1];
            app.Stimulus_LineColor.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_LineColor.Tag = 'Stimulus_LineColor';
            app.Stimulus_LineColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_LineColor.Value = 0.7;
            app.EditField_5Label_2 = uilabel(app.StimulusvariablesPanel);
            app.EditField_5Label_2.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.EditField_5Label_2.Text = 'Flash Color';
            app.Stimulus_FlashColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_FlashColor.Limits = [0 1];
            app.Stimulus_FlashColor.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_FlashColor.Tag = 'Stimulus_FlashColor';
            app.Stimulus_FlashColor.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.BackgroundEditFieldLabel_2 = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel_2.Text = 'Dim Color';
            app.Stimulus_DimColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_DimColor.Limits = [0 1];
            app.Stimulus_DimColor.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_DimColor.Tag = 'Stimulus_DimColor';
            app.Stimulus_DimColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_DimColor.Value = 0.5;
            app.BackgroundEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.Stimulus_BackgroundColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_BackgroundColor.Limits = [0 1];
            app.Stimulus_BackgroundColor.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_BackgroundColor.Tag = 'Stimulus_BackgroundColor';
            app.Stimulus_BackgroundColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_BackgroundColor.Value = 0.5;
            app.ReadyCuecolorLabel = uilabel(app.StimulusvariablesPanel);
            app.ReadyCuecolorLabel.Text = 'ReadyCue color';
            app.ReadyCue_Color = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.ReadyCue_Color.Limits = [0 1];
            app.ReadyCue_Color.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.ReadyCue_Color.Tag = 'ReadyCue_Color';
            app.ReadyCue_Color.Value = 0.7;
            app.EditField_2Label = uilabel(app.StimulusvariablesPanel);
            app.EditField_2Label.Text = 'ReadyCue size';
            app.ReadyCue_Size = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.ReadyCue_Size.Limits = [1 Inf];
            app.ReadyCue_Size.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.ReadyCue_Size.Tag = 'ReadyCue_Size';
            app.ReadyCue_Size.Value = 12;
            app.SegmentthicknessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentthicknessEditFieldLabel.Text = 'Segment thickness';
            app.Stimulus_SegThick = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SegThick.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_SegThick.Tag = 'Stimulus_SegThick';
            app.Stimulus_SegThick.Value = 13;
            app.InputControlPanel = uipanel(app.SettingsTab);
            app.InputControlPanel.Title = 'Input Control';
            app.TurnMagEditFieldLabel = uilabel(app.InputControlPanel);
            app.TurnMagEditFieldLabel.Text = 'Turn Mag.';
            app.TurnMag = uieditfield(app.InputControlPanel, 'numeric');
            app.TurnMag.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.TurnMag.Tag = 'TurnMag';
            app.TurnMag.Value = 1000;
            app.RoundUp = uicheckbox(app.InputControlPanel);
            app.RoundUp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.RoundUp.Tag = 'RoundUp';
            app.RoundUp.Tooltip = {'Choose to accept the mouse''s choice at timeout if they turn the wheel *almost* to the choice.'};
            app.RoundUp.Text = 'Round up choice';
            app.RoundUpEditFieldLabel = uilabel(app.InputControlPanel);
            app.RoundUpEditFieldLabel.Text = 'Round Up (%)';
            app.RoundUpVal = uieditfield(app.InputControlPanel, 'numeric');
            app.RoundUpVal.Limits = [0 110];
            app.RoundUpVal.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.RoundUpVal.Tag = 'RoundUpVal';
            app.RoundUpVal.Tooltip = {'Choose the percentage threshold to accept the mouse''s choice if they do not turn the wheel fully.'};
            app.RoundUpVal.Value = 95;
            app.Stimulus_FinishLine = uicheckbox(app.InputControlPanel);
            app.Stimulus_FinishLine.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Stimulus_FinishLine.Tag = 'Stimulus_FinishLine';
            app.Stimulus_FinishLine.Tooltip = {'DIsplay a marker that will indicate how far the mouse has to turn the wheel to input a choice. Based on stimulus variables.'};
            app.Stimulus_FinishLine.Text = 'Finish line?';
            app.IntertrialMalCancel = uicheckbox(app.InputControlPanel);
            app.IntertrialMalCancel.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.IntertrialMalCancel.Tag = 'IntertrialMalCancel';
            app.IntertrialMalCancel.Tooltip = {'Check this box to end the current trial if the mouse pokes left or right when the ready cue is up. Only a center poke will start the next trial.'};
            app.IntertrialMalCancel.Text = 'Intertrial Mal';
            app.IntertrialMalCancel.Value = true;
            app.IntertrialMalSec = uieditfield(app.InputControlPanel, 'numeric');
            app.IntertrialMalSec.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.IntertrialMalSec.Tag = 'IntertrialMalSec';
            app.IntertrialMalSec.Tooltip = {'If the box is checked, a new trial will not begin until this many seconds after the mouse last poked Left or Right during the intertrial period. Once a new trial does begin, it will cancel if the mouse pokes anything but the center.'};
            app.IntertrialMalSec.Value = 1;
            app.DuringTrialEditFieldLabel = uilabel(app.InputControlPanel);
            app.DuringTrialEditFieldLabel.Text = 'Input delay';
            app.Input_Delay_Start = uieditfield(app.InputControlPanel, 'numeric');
            app.Input_Delay_Start.Limits = [0 2];
            app.Input_Delay_Start.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Input_Delay_Start.Tag = 'Input_Delay_Start';
            app.Input_Delay_Start.Tooltip = {'How long the mouse needs to hold their choice before it is accepted. Prevents mice from mindlessly choosing. Less than a second is best.'};
            app.Input_Delay_Start.Value = 0.5;
            app.EditField_6Label = uilabel(app.InputControlPanel);
            app.EditField_6Label.Text = 'Hold Still Thresh';
            app.Hold_Still_Thresh = uieditfield(app.InputControlPanel, 'numeric');
            app.Hold_Still_Thresh.Limits = [0 500];
            app.Hold_Still_Thresh.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Hold_Still_Thresh.Tag = 'Hold_Still_Thresh';
            app.Hold_Still_Thresh.Tooltip = {'Speed mouse must keep wheel under during "Hold Still" interval before trial.'};
            app.Hold_Still_Thresh.Value = 10;
            app.Input_Delay_Respond = uieditfield(app.InputControlPanel, 'numeric');
            app.Input_Delay_Respond.Limits = [0 2];
            app.Input_Delay_Respond.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Input_Delay_Respond.Tag = 'Input_Delay_Respond';
            app.Input_Delay_Respond.Tooltip = {'How long the mouse needs to hold their choice before it is accepted. Prevents mice from mindlessly choosing. Less than a second is best.'};
            app.Input_Delay_Respond.Value = 0.1;
            app.ConfirmChoice = uicheckbox(app.InputControlPanel);
            app.ConfirmChoice.Tag = 'ConfirmChoice';
            app.ConfirmChoice.Tooltip = {'If the mouse stands in front of the correct choice during the Input Ignored interval, the 5-dashed line contour will blink to show the impatient mouse they are indeed going to be rewarded.'};
            app.ConfirmChoice.Text = 'Confirm choice';
            app.ConfirmChoice.Value = true;
            app.Level_EasyLvList = uieditfield(app.SettingsTab, 'text');
            app.Level_EasyLvList.Tag = 'Level_EasyLvList';
            app.Level_EasyLvList.Value = '1';
            app.Level_HardLvList = uieditfield(app.SettingsTab, 'text');
            app.Level_HardLvList.Tag = 'Level_HardLvList';
            app.Level_HardLvList.Value = '2:6';
            app.HighLvProbEditFieldLabel = uilabel(app.SettingsTab);
            app.HighLvProbEditFieldLabel.Text = 'High Lv. Prob';
            app.Level_HardLvProb = uieditfield(app.SettingsTab, 'numeric');
            app.Level_HardLvProb.Limits = [0 1];
            app.Level_HardLvProb.ValueChangedFcn = createCallbackFcn(app, @Level_HardLvProbValueChanged, true);
            app.Level_HardLvProb.Tag = 'Level_HardLvProb';
            app.Level_HardLvProb.Value = 0.8;
            app.EasyLvProbLabel = uilabel(app.SettingsTab);
            app.EasyLvProbLabel.Text = 'Easy Lv. Prob';
            app.Level_EasyLvProb = uieditfield(app.SettingsTab, 'numeric');
            app.Level_EasyLvProb.Limits = [0 1];
            app.Level_EasyLvProb.ValueChangedFcn = createCallbackFcn(app, @Level_EasyLvProbValueChanged, true);
            app.Level_EasyLvProb.Tag = 'Level_EasyLvProb';
            app.Level_EasyLvProb.Value = 0.2;
            app.TemporaryTab = uitab(app.TabGroup);
            app.TemporaryTab.Title = 'Temporary';
            app.uipanel1_2 = uipanel(app.TemporaryTab);
            app.uipanel1_2.Title = 'Level & Side';
            app.uipanel1_2.Tag = 'uipanel1';
            app.DistractorsSpinnerLabel_2 = uilabel(app.uipanel1_2);
            app.DistractorsSpinnerLabel_2.Text = 'Level';
            app.Starting_opacity_Temp = uispinner(app.uipanel1_2);
            app.Starting_opacity_Temp.Limits = [1 20];
            app.Starting_opacity_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Starting_opacity_Temp.Tag = 'Starting_opacity_Temp';
            app.Starting_opacity_Temp.Value = 1;
            app.EasyTrials_Temp = uicheckbox(app.uipanel1_2);
            app.EasyTrials_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.EasyTrials_Temp.Tag = 'EasyTrials_Temp';
            app.EasyTrials_Temp.Tooltip = 'Defines if easier trials should be shown or not';
            app.EasyTrials_Temp.Text = 'Easy %';
            app.EasyTrials_Temp.Value = true;
            app.prob_list_Temp = uitextarea(app.uipanel1_2);
            app.prob_list_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.prob_list_Temp.Tag = 'prob_list_Temp';
            app.uipanel3_2 = uipanel(app.TemporaryTab);
            app.uipanel3_2.Title = 'Trial Intervals';
            app.uipanel3_2.Tag = 'uipanel3';
            app.Input_ignored_Temp = uicheckbox(app.uipanel3_2);
            app.Input_ignored_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Input_ignored_Temp.Tag = 'Input_ignored_Temp';
            app.Input_ignored_Temp.Text = 'Input ignored';
            app.Pokes_ignored_time_Temp = uieditfield(app.uipanel3_2, 'text');
            app.Pokes_ignored_time_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Pokes_ignored_time_Temp.Tag = 'Pokes_ignored_time_Temp';
            app.Pokes_ignored_time_Temp.Tooltip = 'ignore all input for how many seconds after trial begin';
            app.Pokes_ignored_time_Temp.Value = '2';
            app.text8_2 = uilabel(app.uipanel3_2);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = 'Penalty(s)';
            app.Penalty_time_Temp = uieditfield(app.uipanel3_2, 'text');
            app.Penalty_time_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Penalty_time_Temp.Tag = 'Penalty_time_Temp';
            app.Penalty_time_Temp.Tooltip = 'penalty delay in seconds';
            app.Penalty_time_Temp.Value = '1';
            app.text10_2 = uilabel(app.uipanel3_2);
            app.text10_2.Tag = 'text10';
            app.text10_2.Text = 'Intertrial (s)';
            app.Intertrial_time_Temp = uieditfield(app.uipanel3_2, 'text');
            app.Intertrial_time_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Intertrial_time_Temp.Tag = 'Intertrial_time_Temp';
            app.Intertrial_time_Temp.Tooltip = 'Interval between each trial in seconds';
            app.Intertrial_time_Temp.Value = '1';
            app.EditField_5Label_3 = uilabel(app.uipanel3_2);
            app.EditField_5Label_3.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.EditField_5Label_3.Text = 'Hold still (s):';
            app.HoldStill_Temp = uieditfield(app.uipanel3_2, 'numeric');
            app.HoldStill_Temp.Limits = [0 10];
            app.HoldStill_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.HoldStill_Temp.Tag = 'HoldStill_Temp';
            app.HoldStill_Temp.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.Repeat_wrong_Temp = uicheckbox(app.uipanel3_2);
            app.Repeat_wrong_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Repeat_wrong_Temp.Tag = 'Repeat_wrong_Temp';
            app.Repeat_wrong_Temp.Tooltip = 'repeat the same side if last one was wrong';
            app.Repeat_wrong_Temp.Text = 'repeat wrong';
            app.OnlyCorrect_Temp = uicheckbox(app.uipanel3_2);
            app.OnlyCorrect_Temp.Tag = 'OnlyCorrect_Temp';
            app.OnlyCorrect_Temp.Text = 'Only Correct';
            app.Box_OCPulse_Temp = uieditfield(app.uipanel3_2, 'numeric');
            app.Box_OCPulse_Temp.Limits = [0 10];
            app.Box_OCPulse_Temp.Tag = 'Box_OCPulse_Temp';
            app.Box_OCPulse_Temp.Tooltip = {'How many drops to give if using the Only Correct setting, where the mouse is able to answer correctly after a wrong choice.'};
            app.Box_OCPulse_Temp.Value = 1;
            app.InputControlPanel_2 = uipanel(app.TemporaryTab);
            app.InputControlPanel_2.Title = 'Input Control';
            app.IntertrialMalCancel_Temp = uicheckbox(app.InputControlPanel_2);
            app.IntertrialMalCancel_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.IntertrialMalCancel_Temp.Tag = 'IntertrialMalCancel_Temp';
            app.IntertrialMalCancel_Temp.Tooltip = {'Check this box to end the current trial if the mouse pokes left or right when the ready cue is up. Only a center poke will start the next trial.'};
            app.IntertrialMalCancel_Temp.Text = 'Intertrial Mal';
            app.IntertrialMalCancel_Temp.Value = true;
            app.IntertrialMalSec_Temp = uieditfield(app.InputControlPanel_2, 'numeric');
            app.IntertrialMalSec_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.IntertrialMalSec_Temp.Tag = 'IntertrialMalSec_Temp';
            app.IntertrialMalSec_Temp.Tooltip = {'If the box is checked, a new trial will not begin until this many seconds after the mouse last poked Left or Right during the intertrial period. Once a new trial does begin, it will cancel if the mouse pokes anything but the center.'};
            app.IntertrialMalSec_Temp.Value = 1;
            app.EditField_6Label_2 = uilabel(app.InputControlPanel_2);
            app.EditField_6Label_2.Text = 'Hold Still Thresh';
            app.Hold_Still_Thresh_Temp = uieditfield(app.InputControlPanel_2, 'numeric');
            app.Hold_Still_Thresh_Temp.Limits = [0 500];
            app.Hold_Still_Thresh_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.Hold_Still_Thresh_Temp.Tag = 'Hold_Still_Thresh_Temp';
            app.Hold_Still_Thresh_Temp.Tooltip = {'Speed mouse must keep wheel under during "Hold Still" interval before trial.'};
            app.Hold_Still_Thresh_Temp.Value = 10;
            app.ExpireAfterButtonGroup = uibuttongroup(app.TemporaryTab);
            app.ExpireAfterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.ExpireAfterButtonGroup.Title = 'Expire After';
            app.PerformanceThreshold_Temp = uiradiobutton(app.ExpireAfterButtonGroup);
            app.PerformanceThreshold_Temp.Tag = 'PerformanceThreshold_Temp';
            app.PerformanceThreshold_Temp.Tooltip = {'Temporary settings will persist until performance at Level 1 crosses the threshold'};
            app.PerformanceThreshold_Temp.Text = 'Performance';
            app.PerformanceThreshold_Temp.Value = true;
            app.TrialNumber_Temp = uiradiobutton(app.ExpireAfterButtonGroup);
            app.TrialNumber_Temp.Tag = 'TrialNumber_Temp';
            app.TrialNumber_Temp.Text = 'Trial Number';
            app.PerfThresh_Temp = uieditfield(app.ExpireAfterButtonGroup, 'numeric');
            app.PerfThresh_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.PerfThresh_Temp.Tag = 'PerfThresh_Temp';
            app.TrialCount_Temp = uieditfield(app.ExpireAfterButtonGroup, 'numeric');
            app.TrialCount_Temp.ValueChangedFcn = createCallbackFcn(app, @GUIValueChange, true);
            app.TrialCount_Temp.Tag = 'TrialCount_Temp';
            app.TrialsRemainingLabel = uilabel(app.ExpireAfterButtonGroup);
            app.TrialsRemainingLabel.Text = '_ Trials Remaining';
            app.TempOff_Temp = uiradiobutton(app.ExpireAfterButtonGroup);
            app.TempOff_Temp.Tag = 'TempOff_Temp';
            app.TempOff_Temp.Text = 'Off';
            app.uipanel4_2 = uipanel(app.TemporaryTab);
            app.uipanel4_2.Title = 'Reward / Penalty';
            app.uipanel4_2.Tag = 'uipanel4';
            app.LeftValveButton_Temp = uibutton(app.uipanel4_2, 'push');
            app.LeftValveButton_Temp.Tag = 'LeftValveButton_Temp';
            app.LeftValveButton_Temp.Tooltip = {'Press to test Left water reward size'};
            app.LeftValveButton_Temp.Text = 'L';
            app.Box_Lrewardtime_Temp = uieditfield(app.uipanel4_2, 'text');
            app.Box_Lrewardtime_Temp.Tag = 'Box_Lrewardtime_Temp';
            app.Box_Lrewardtime_Temp.Tooltip = 'How long the vale is open in seconds';
            app.Box_Lrewardtime_Temp.Value = '0.04';
            app.Box_LeftPulse_Temp = uieditfield(app.uipanel4_2, 'numeric');
            app.Box_LeftPulse_Temp.Limits = [1 10];
            app.Box_LeftPulse_Temp.Tag = 'Box_LeftPulse_Temp';
            app.Box_LeftPulse_Temp.Tooltip = {'How many pulses from the left water valve'};
            app.Box_LeftPulse_Temp.Value = 1;
            app.RightValveButton_Temp = uibutton(app.uipanel4_2, 'push');
            app.RightValveButton_Temp.Tag = 'RightValveButton_Temp';
            app.RightValveButton_Temp.Tooltip = {'Press to test Right water reward size'};
            app.RightValveButton_Temp.Text = 'R';
            app.Box_Rrewardtime_Temp = uieditfield(app.uipanel4_2, 'text');
            app.Box_Rrewardtime_Temp.Tag = 'Box_Rrewardtime_Temp';
            app.Box_Rrewardtime_Temp.Tooltip = 'How long the vale is open in seconds';
            app.Box_Rrewardtime_Temp.Value = '0.04';
            app.Box_RightPulse_Temp = uieditfield(app.uipanel4_2, 'numeric');
            app.Box_RightPulse_Temp.Limits = [1 10];
            app.Box_RightPulse_Temp.Tag = 'Box_RightPulse_Temp';
            app.Box_RightPulse_Temp.Tooltip = {'How many pulses from the rightwater valve'};
            app.Box_RightPulse_Temp.Value = 1;
            app.NotesTab = uitab(app.TabGroup);
            app.NotesTab.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.NotesTab.Title = 'Notes';
            app.NotesTab.Tag = 'INO';
            app.NotesText = uitextarea(app.NotesTab);
            app.NotesText.Tag = 'NotesText';
            app.NotesText.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.MsgBox = uitextarea(app.OutputTab);
            app.MsgBox.Tag = 'MsgBox';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.PerformanceTab.Tag = 'PerfTab';
            app.TimersTab = uitab(app.TabGroup);
            app.TimersTab.Title = 'Timers';
            app.AllTimeTab = uitab(app.TabGroup);
            app.AllTimeTab.Title = 'All Time';
            app.WeightWaterTab = uitab(app.TabGroup);
            app.WeightWaterTab.Title = 'Weight/Water';
            app.OtherUnusedTab = uitab(app.TabGroup);
            app.OtherUnusedTab.Title = 'Other / Unused';
            app.uipanel11 = uipanel(app.OtherUnusedTab);
            app.uipanel11.Title = 'Orientation Tuning';
            app.uipanel11.Tag = 'uipanel11';
            app.text79 = uilabel(app.uipanel11);
            app.text79.Tag = 'text79';
            app.text79.Text = 'Repeats:';
            app.text81 = uilabel(app.uipanel11);
            app.text81.Tag = 'text81';
            app.text81.Text = 'Duration:';
            app.text82 = uilabel(app.uipanel11);
            app.text82.Tag = 'text82';
            app.text82.Text = 'Orientations:';
            app.text83 = uilabel(app.uipanel11);
            app.text83.Tag = 'text83';
            app.text83.Text = 'Interval:';
            app.text84 = uilabel(app.uipanel11);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Bar size:';
            app.text85 = uilabel(app.uipanel11);
            app.text85.Tag = 'text85';
            app.text85.Text = 'Frequency:';
            app.Orientation = uibutton(app.uipanel11, 'push');
            app.Orientation.ButtonPushedFcn = createCallbackFcn(app, @Orientation_Callback, true);
            app.Orientation.Tag = 'Orientation';
            app.Orientation.Tooltip = 'show orientation tuning only (no input required)';
            app.Orientation.Text = 'Orientation Tuning Only';
            app.Ori_Repeats = uieditfield(app.uipanel11, 'text');
            app.Ori_Repeats.Tag = 'Ori_Repeats';
            app.Ori_Repeats.Tooltip = 'how many repeats of all orientations';
            app.Ori_Repeats.Value = '2';
            app.Ori_Duration = uieditfield(app.uipanel11, 'text');
            app.Ori_Duration.Tag = 'Ori_Duration';
            app.Ori_Duration.Tooltip = 'how long to show each orientation';
            app.Ori_Duration.Value = '2';
            app.Ori_Orientations = uidropdown(app.uipanel11);
            app.Ori_Orientations.Items = {'4', '8', '16'};
            app.Ori_Orientations.Tag = 'Ori_Orientations';
            app.Ori_Orientations.Tooltip = 'total number of equally spaced orientations';
            app.Ori_Orientations.Value = '8';
            app.Ori_Interval = uieditfield(app.uipanel11, 'text');
            app.Ori_Interval.Tag = 'Ori_Interval';
            app.Ori_Interval.Tooltip = 'interval between each orientation';
            app.Ori_Interval.Value = '2';
            app.Ori_Random = uicheckbox(app.uipanel11);
            app.Ori_Random.Tag = 'Ori_Random';
            app.Ori_Random.Tooltip = 'randomize orientations';
            app.Ori_Random.Text = 'random';
            app.Ori_Bar_Size = uieditfield(app.uipanel11, 'text');
            app.Ori_Bar_Size.Tag = 'Ori_Bar_Size';
            app.Ori_Bar_Size.Tooltip = 'size gratings';
            app.Ori_Bar_Size.Value = '170';
            app.Ori_Frequency = uieditfield(app.uipanel11, 'text');
            app.Ori_Frequency.Tag = 'Ori_Frequency';
            app.Ori_Frequency.Tooltip = 'temporal frequency of grating';
            app.Ori_Frequency.Value = '0.5';
            app.Stimulus_Orient = uieditfield(app.uipanel11, 'text');
            app.Stimulus_Orient.Tag = 'Stimulus_Orient';
            app.Stimulus_Orient.Tooltip = {'Orientation of stimulus in degrees'};
            app.Stimulus_Orient.Value = '0';
            app.uipanel12 = uipanel(app.OtherUnusedTab);
            app.uipanel12.Title = 'Difficulty Adjust';
            app.uipanel12.Tag = 'uipanel12';
            app.GridLayout4 = uigridlayout(app.uipanel12);
            app.DiffAdjustMethod = uidropdown(app.GridLayout4);
            app.DiffAdjustMethod.Items = {'Inc/dec after trials', 'Step up after trials', 'Random diff in range'};
            app.DiffAdjustMethod.Tag = 'DiffAdjustMethod';
            app.DiffAdjustMethod.Value = 'Inc/dec after trials';
            app.Raise_bg_with_perf = uicheckbox(app.GridLayout4);
            app.Raise_bg_with_perf.Tag = 'Raise_bg_with_perf';
            app.Raise_bg_with_perf.Text = 'inc diff after trials:';
            app.RaiseDiffAfterBins = uieditfield(app.GridLayout4, 'text');
            app.RaiseDiffAfterBins.Tag = 'RaiseDiffAfterBins';
            app.RaiseDiffAfterBins.Tooltip = 'adjust background after how many correct/wrong trials';
            app.RaiseDiffAfterBins.Value = '10';
            app.Lower_bg_with_perf = uicheckbox(app.GridLayout4);
            app.Lower_bg_with_perf.Tag = 'Lower_bg_with_perf';
            app.Lower_bg_with_perf.Text = 'dec diff after trials:';
            app.LowerDiffAfterBins = uieditfield(app.GridLayout4, 'text');
            app.LowerDiffAfterBins.Tag = 'LowerDiffAfterBins';
            app.LowerDiffAfterBins.Tooltip = 'adjust background after how many correct/wrong trials';
            app.LowerDiffAfterBins.Value = '10';
            app.text94 = uilabel(app.GridLayout4);
            app.text94.Tag = 'text94';
            app.text94.Text = 'inc thres:';
            app.RaiseThres = uieditfield(app.GridLayout4, 'text');
            app.RaiseThres.Tag = 'RaiseThres';
            app.RaiseThres.Tooltip = 'adjust background after how many correct/wrong trials';
            app.RaiseThres.Value = '0.65';
            app.text95 = uilabel(app.GridLayout4);
            app.text95.Tag = 'text95';
            app.text95.Text = 'dec thres:';
            app.LowerThres = uieditfield(app.GridLayout4, 'text');
            app.LowerThres.Tag = 'LowerThres';
            app.LowerThres.Tooltip = 'adjust background after how many correct/wrong trials';
            app.LowerThres.Value = '0.5';
            app.text96 = uilabel(app.GridLayout4);
            app.text96.Tag = 'text96';
            app.text96.Text = 'Adj Method:';
            app.text97 = uilabel(app.GridLayout4);
            app.text97.Tag = 'text97';
            app.text97.Text = 'step up after trials:';
            app.StepUpAfter = uieditfield(app.GridLayout4, 'text');
            app.StepUpAfter.Tag = 'StepUpAfter';
            app.StepUpAfter.Tooltip = 'adjust background after how many correct/wrong trials';
            app.StepUpAfter.Value = '20';
            app.text98 = uilabel(app.GridLayout4);
            app.text98.Tag = 'text98';
            app.text98.Text = 'step:';
            app.RandomMax = uieditfield(app.GridLayout4, 'text');
            app.RandomMax.Tag = 'RandomMax';
            app.RandomMax.Tooltip = 'adjust background after how many correct/wrong trials';
            app.RandomMax.Value = '1';
            app.text99 = uilabel(app.GridLayout4);
            app.text99.Tag = 'text99';
            app.text99.Text = 'random max:';
            app.RandomStep = uieditfield(app.GridLayout4, 'text');
            app.RandomStep.Tag = 'RandomStep';
            app.RandomStep.Tooltip = 'adjust background after how many correct/wrong trials';
            app.RandomStep.Value = '0.1';
            app.text100 = uilabel(app.GridLayout4);
            app.text100.Tag = 'text100';
            app.text100.Text = 'random min:';
            app.RandomMin = uieditfield(app.GridLayout4, 'text');
            app.RandomMin.Tag = 'RandomMin';
            app.RandomMin.Tooltip = 'adjust background after how many correct/wrong trials';
            app.RandomMin.Value = '0.4';
            app.text123 = uilabel(app.GridLayout4);
            app.text123.Tag = 'text123';
            app.text123.Text = 'sub bin size:';
            app.sub_bin_size = uieditfield(app.GridLayout4, 'text');
            app.sub_bin_size.Tag = 'sub_bin_size';
            app.sub_bin_size.Tooltip = 'size of sub bin (to measure std threshold)';
            app.sub_bin_size.Value = '5';
            app.text124 = uilabel(app.GridLayout4);
            app.text124.Tag = 'text124';
            app.text124.Text = 'std thres:';
            app.stdthreshold = uieditfield(app.GridLayout4, 'text');
            app.stdthreshold.Tag = 'stdthreshold';
            app.stdthreshold.Tooltip = 'standard deviation threshold to change difficulty';
            app.stdthreshold.Value = '0.5';
            app.uipanel8 = uipanel(app.OtherUnusedTab);
            app.uipanel8.Title = 'Input training';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout3 = uigridlayout(app.uipanel8);
            app.Show_two_bars = uicheckbox(app.GridLayout3);
            app.Show_two_bars.Tag = 'Show_two_bars';
            app.Show_two_bars.Tooltip = 'Show two bars for input training only';
            app.Show_two_bars.Text = 'show two training bars';
            app.Show_two_bars.Value = true;
            app.TrainingChoices = uidropdown(app.GridLayout3);
            app.TrainingChoices.Items = {'Unlimited Rewards', 'Only Correct or Timeout', 'None', 'Go/No-Go Practice', 'Wheel Practice Early', 'Wheel Practice Late', 'Wheel Practice - No wrong choice', 'NosePoke Training 1', 'NosePoke Training 2', 'NosePoke Training 3', 'NosePoke Training 4', 'NosePoke Training 5'};
            app.TrainingChoices.Tag = 'TrainingChoices';
            app.TrainingChoices.Tooltip = {'Choose Special Rules for training new mice.'};
            app.TrainingChoices.Value = 'None';
            app.Stimulus_TrainingFlashifWrong = uicheckbox(app.GridLayout3);
            app.Stimulus_TrainingFlashifWrong.Tag = 'Stimulus_TrainingFlashifWrong';
            app.Stimulus_TrainingFlashifWrong.Text = 'Flash if wrong?';
            app.Train = uibutton(app.GridLayout3, 'push');
            app.Train.ButtonPushedFcn = createCallbackFcn(app, @Train_Callback, true);
            app.Train.Tag = 'Train';
            app.Train.Tooltip = 'Start input training (either choice will get reward)';
            app.Train.Text = 'Training Mode';
            app.Ext_trigger = uicheckbox(app.OtherUnusedTab);
            app.Ext_trigger.Tag = 'Ext_trigger';
            app.Ext_trigger.Tooltip = 'start experiment by an external trigger';
            app.Ext_trigger.Text = 'ext trigger';
            app.EquipmentPanel = uipanel(app.OtherUnusedTab);
            app.EquipmentPanel.Title = 'Equipment';
            app.OpenL = uibutton(app.EquipmentPanel, 'state');
            app.OpenL.ValueChangedFcn = createCallbackFcn(app, @OpenLValueChanged, true);
            app.OpenL.Tag = 'OpenL';
            app.OpenL.Text = 'Open L';
            app.OpenR = uibutton(app.EquipmentPanel, 'state');
            app.OpenR.ValueChangedFcn = createCallbackFcn(app, @OpenRValueChanged, true);
            app.OpenR.Tag = 'OpenR';
            app.OpenR.Text = 'Open R';
            app.OpenBoth = uibutton(app.EquipmentPanel, 'state');
            app.OpenBoth.ValueChangedFcn = createCallbackFcn(app, @OpenBothValueChanged, true);
            app.OpenBoth.Tag = 'OpenBoth';
            app.OpenBoth.Text = 'Open Both';
            app.ResetAll = uibutton(app.EquipmentPanel, 'state');
            app.ResetAll.ValueChangedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.ResetAll.Tag = 'ResetAll';
            app.ResetAll.Text = 'RESET ALL';
            app.LoadCompButton = uibutton(app.EquipmentPanel, 'push');
            app.LoadCompButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCompButtonPushed, true);
            app.LoadCompButton.Tag = 'LoadCompButton';
            app.LoadCompButton.Text = 'Load Computer Settings';
            app.SaveCompButton = uibutton(app.EquipmentPanel, 'push');
            app.SaveCompButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCompButtonPushed, true);
            app.SaveCompButton.Tag = 'SaveCompButton';
            app.SaveCompButton.Text = 'Save Computer Settings';
            app.Curtain = uibutton(app.EquipmentPanel, 'push');
            app.Curtain.ButtonPushedFcn = createCallbackFcn(app, @CurtainButtonPushed, true);
            app.Curtain.Tag = 'Curtain';
            app.Curtain.Icon = fullfile(pathToMLAPP, 'imgs', 'curtain.png');
            app.Curtain.Tooltip = {'Display a solid color, to use as a light'};
            app.Curtain.Text = '';
            app.VideoButton = uibutton(app.EquipmentPanel, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Tag = 'VideoButton';
            app.VideoButton.Icon = fullfile(pathToMLAPP, 'imgs', 'video.png');
            app.VideoButton.Tooltip = {'Click to open up to 2 video feeds'};
            app.VideoButton.Text = '';
            app.BaseButton = uibutton(app.EquipmentPanel, 'push');
            app.BaseButton.ButtonPushedFcn = createCallbackFcn(app, @BaseButtonPushed, true);
            app.BaseButton.Tag = 'BaseButton';
            app.BaseButton.Text = 'Base';
            app.ArduinosDropDownLabel = uilabel(app.OtherUnusedTab);
            app.ArduinosDropDownLabel.Text = 'Arduino(s):';
            app.ArduinosDropDown = uidropdown(app.OtherUnusedTab);
            app.ArduinosDropDown.Tag = 'ArduinosDropDown';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.Tag = 'Analysis';
            app.Git = uibutton(app.figure1, 'push');
            app.Git.ButtonPushedFcn = createCallbackFcn(app, @GitButtonPushed, true);
            app.Git.Tag = 'Git';
            app.Git.Icon = fullfile(pathToMLAPP, 'imgs', 'git.png');
            app.Git.Tooltip = {'Update this App by pulling from stable Git repo'};
            app.Git.Text = '';
            app.Folder = uibutton(app.figure1, 'push');
            app.Folder.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.Folder.Tag = 'Folder';
            app.Folder.Icon = fullfile(pathToMLAPP, 'imgs', 'folder.png');
            app.Folder.Tooltip = {'Open mouse''s folder to see files'};
            app.Folder.Text = '';
            app.GraphPopOut = uibutton(app.figure1, 'push');
            app.GraphPopOut.ButtonPushedFcn = createCallbackFcn(app, @GraphPopOutButtonPushed, true);
            app.GraphPopOut.Tag = 'GraphPopOut';
            app.GraphPopOut.Icon = fullfile(pathToMLAPP, 'imgs', 'popout.png');
            app.GraphPopOut.Tooltip = {'Pop out the All Time Graph'};
            app.GraphPopOut.Text = '';
            app.GraphPopOut_2 = uibutton(app.figure1, 'push');
            app.GraphPopOut_2.ButtonPushedFcn = createCallbackFcn(app, @GraphPopOut_2ButtonPushed, true);
            app.GraphPopOut_2.Tag = 'GraphPopOut_2';
            app.GraphPopOut_2.Icon = fullfile(pathToMLAPP, 'imgs', 'popout.png');
            app.GraphPopOut_2.Tooltip = {'Pop out Today''s Performance graph'};
            app.GraphPopOut_2.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorBox_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @BehaviorBox_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end